classdef okno_glowne < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SystemkomputerowegowspomaganiadiagnostykimedycznejUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        ListapacjentwListBoxLabel  matlab.ui.control.Label
        ListapacjentwListBox       matlab.ui.control.ListBox
        WybierzseriedanychdlaoknapierwszegoListBoxLabel  matlab.ui.control.Label
        WybierzseriedanychdlaoknapierwszegoListBox  matlab.ui.control.ListBox
        WybierzseriedanychdlaoknadrugiegoListBoxLabel  matlab.ui.control.Label
        WybierzseriedanychdlaoknadrugiegoListBox  matlab.ui.control.ListBox
        CenterPanel                matlab.ui.container.Panel
        KontrastSliderLabel        matlab.ui.control.Label
        KontrastSlider             matlab.ui.control.Slider
        KontrastSlider_2Label      matlab.ui.control.Label
        KontrastSlider_2           matlab.ui.control.Slider
        JasnoSliderLabel           matlab.ui.control.Label
        JasnoSlider                matlab.ui.control.Slider
        JasnoSlider_2Label         matlab.ui.control.Label
        JasnoSlider_2              matlab.ui.control.Slider
        Right                      matlab.ui.control.Image
        Left                       matlab.ui.control.Image
        Number_image               matlab.ui.control.NumericEditField
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
        SegmentujButton            matlab.ui.control.Button
        WeryfikujButton            matlab.ui.control.Button
        ObliczpowierzchnieButton   matlab.ui.control.Button
        ObliczobjtoButton          matlab.ui.control.Button
        PolepowierzchniLabel       matlab.ui.control.Label
        wynikLabel                 matlab.ui.control.Label
        ObjtoLabel                 matlab.ui.control.Label
        wynikLabel_2               matlab.ui.control.Label
        WynikiweryfikacjiLabel     matlab.ui.control.Label
        wynikLabel_3               matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
       I,
       I_drugi,
       index
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        names = dir('DANE/SERIE/*.mat');
            for k=1:length(names)
                series(k,1) = load(fullfile('DANE/SERIE/',names(k,1).name));
                patients(k,1)=convertCharsToStrings(names(k,1).name);
            end

            assignin('base','series',series);
            app.ListapacjentwListBox.Items=patients;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SystemkomputerowegowspomaganiadiagnostykimedycznejUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {665, 665, 665};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {665, 665};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 221};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Value changed function: ListapacjentwListBox
        function ListapacjentwListBoxValueChanged(app, event)
            series= evalin('base','series')
            listitems = app.ListapacjentwListBox.Items;
            [~, app.index] = ismember(app.ListapacjentwListBox.Value, listitems)
            for n=1:(size(series(app.index).s.serie,1))
           %app.I=series(index).s.serie{n,1};
           s(n)=n;
            end
           
           app.WybierzseriedanychdlaoknapierwszegoListBox.Items=arrayfun(@num2str,s,'un',0);
           app.WybierzseriedanychdlaoknadrugiegoListBox.Items=arrayfun(@num2str,s,'un',0);
        end

        % Value changed function: 
        % WybierzseriedanychdlaoknapierwszegoListBox
        function WybierzseriedanychdlaoknapierwszegoListBoxValueChanged(app, event)
            series= evalin('base','series')
            value = app.WybierzseriedanychdlaoknapierwszegoListBox.Value;
            app.I=series(app.index).s.serie{str2double(value),1};
            sz=size(app.I());
            max_image=sz(3);
            app.Number_image.Limits=[1, max_image];
            number = app.Number_image.Value;
            imshow(app.I(:,:,number),[],'parent',app.UIAxes)
            
        end

        % Value changed function: Number_image
        function Number_imageValueChanged(app, event)
            number = app.Number_image.Value;
            imshow(app.I(:,:,number),[],'parent',app.UIAxes)
            imshow(app.I_drugi(:,:,number),[],'parent',app.UIAxes)
        end

        % Image clicked function: Right
        function RightImageClicked(app, event)
            limits=app.Number_image.Limits;
            if(app.Number_image.Value~= limits(2))
            app.Number_image.Value =app.Number_image.Value + 1;
            imshow(app.I(:,:,app.Number_image.Value),[],'parent',app.UIAxes)
            imshow(app.I_drugi(:,:,app.Number_image.Value),[],'parent',app.UIAxes_2)
            else
            app.Number_image.Value =1;
            imshow(app.I(:,:,app.Number_image.Value),[],'parent',app.UIAxes)
            imshow(app.I_drugi(:,:,app.Number_image.Value),[],'parent',app.UIAxes_2)
            end
        end

        % Image clicked function: Left
        function LeftImageClicked(app, event)
            limits=app.Number_image.Limits;
            if(app.Number_image.Value~= limits(1))
            app.Number_image.Value =app.Number_image.Value - 1;
            imshow(app.I(:,:,app.Number_image.Value),[],'parent',app.UIAxes)
            imshow(app.I_drugi(:,:,app.Number_image.Value),[],'parent',app.UIAxes_2)
            else
            app.Number_image.Value =limits(2);
            imshow(app.I(:,:,app.Number_image.Value),[],'parent',app.UIAxes)
            imshow(app.I_drugi(:,:,app.Number_image.Value),[],'parent',app.UIAxes_2)
            end
        end

        % Value changed function: 
        % WybierzseriedanychdlaoknadrugiegoListBox
        function WybierzseriedanychdlaoknadrugiegoListBoxValueChanged(app, event)

            series= evalin('base','series')
            value = app.WybierzseriedanychdlaoknadrugiegoListBox.Value;
            app.I_drugi=series(app.index).s.serie{str2double(value),1};
            sz=size(app.I_drugi());
            max_image=sz(3);
            app.Number_image.Limits=[1, max_image];
            number = app.Number_image.Value;
            imshow(app.I_drugi(:,:,number),[],'parent',app.UIAxes_2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SystemkomputerowegowspomaganiadiagnostykimedycznejUIFigure = uifigure('Visible', 'off');
            app.SystemkomputerowegowspomaganiadiagnostykimedycznejUIFigure.Name = 'System komputerowego wspomagania diagnostyki medycznej';
            app.SystemkomputerowegowspomaganiadiagnostykimedycznejUIFigure.Icon = 'account.png';
            app.SystemkomputerowegowspomaganiadiagnostykimedycznejUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SystemkomputerowegowspomaganiadiagnostykimedycznejUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ListapacjentwListBoxLabel = uilabel(app.LeftPanel);
            app.ListapacjentwListBoxLabel.Text = 'Lista pacjentów';
            app.ListapacjentwListBox = uilistbox(app.LeftPanel);
            app.ListapacjentwListBox.Items = {};
            app.ListapacjentwListBox.ValueChangedFcn = createCallbackFcn(app, @ListapacjentwListBoxValueChanged, true);
            app.ListapacjentwListBox.Value = {};
            app.WybierzseriedanychdlaoknapierwszegoListBoxLabel = uilabel(app.LeftPanel);
            app.WybierzseriedanychdlaoknapierwszegoListBoxLabel.Text = 'Wybierz serie danych dla okna pierwszego';
            app.WybierzseriedanychdlaoknapierwszegoListBox = uilistbox(app.LeftPanel);
            app.WybierzseriedanychdlaoknapierwszegoListBox.Items = {};
            app.WybierzseriedanychdlaoknapierwszegoListBox.ValueChangedFcn = createCallbackFcn(app, @WybierzseriedanychdlaoknapierwszegoListBoxValueChanged, true);
            app.WybierzseriedanychdlaoknapierwszegoListBox.Value = {};
            app.WybierzseriedanychdlaoknadrugiegoListBoxLabel = uilabel(app.LeftPanel);
            app.WybierzseriedanychdlaoknadrugiegoListBoxLabel.Text = 'Wybierz serie danych dla okna drugiego';
            app.WybierzseriedanychdlaoknadrugiegoListBox = uilistbox(app.LeftPanel);
            app.WybierzseriedanychdlaoknadrugiegoListBox.Items = {};
            app.WybierzseriedanychdlaoknadrugiegoListBox.ValueChangedFcn = createCallbackFcn(app, @WybierzseriedanychdlaoknadrugiegoListBoxValueChanged, true);
            app.WybierzseriedanychdlaoknadrugiegoListBox.Value = {};
            app.CenterPanel = uipanel(app.GridLayout);
            app.KontrastSliderLabel = uilabel(app.CenterPanel);
            app.KontrastSliderLabel.Text = {'Kontrast'; ''};
            app.KontrastSlider = uislider(app.CenterPanel);
            app.KontrastSlider.Limits = [0 1];
            app.KontrastSlider.MajorTicks = [];
            app.KontrastSlider.MajorTickLabels = {'', ''};
            app.KontrastSlider.MinorTicks = [];
            app.KontrastSlider.Value = 0.5;
            app.KontrastSlider_2Label = uilabel(app.CenterPanel);
            app.KontrastSlider_2Label.Text = {'Kontrast'; ''};
            app.KontrastSlider_2 = uislider(app.CenterPanel);
            app.KontrastSlider_2.Limits = [0 1];
            app.KontrastSlider_2.MajorTicks = [];
            app.KontrastSlider_2.MinorTicks = [];
            app.KontrastSlider_2.Value = 0.5;
            app.JasnoSliderLabel = uilabel(app.CenterPanel);
            app.JasnoSliderLabel.Text = 'Jasność';
            app.JasnoSlider = uislider(app.CenterPanel);
            app.JasnoSlider.Limits = [-1000 1000];
            app.JasnoSlider.MajorTicks = [];
            app.JasnoSlider.MinorTicks = [];
            app.JasnoSlider_2Label = uilabel(app.CenterPanel);
            app.JasnoSlider_2Label.Text = {'Jasność'; ''; ''};
            app.JasnoSlider_2 = uislider(app.CenterPanel);
            app.JasnoSlider_2.Limits = [-1000 1000];
            app.JasnoSlider_2.MajorTicks = [];
            app.JasnoSlider_2.MinorTicks = [];
            app.Right = uiimage(app.CenterPanel);
            app.Right.ImageClickedFcn = createCallbackFcn(app, @RightImageClicked, true);
            app.Right.ImageSource = 'next-button.png';
            app.Left = uiimage(app.CenterPanel);
            app.Left.ImageClickedFcn = createCallbackFcn(app, @LeftImageClicked, true);
            app.Left.ImageSource = 'previous.png';
            app.Number_image = uieditfield(app.CenterPanel, 'numeric');
            app.Number_image.ValueChangedFcn = createCallbackFcn(app, @Number_imageValueChanged, true);
            app.UIAxes = uiaxes(app.CenterPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.CenterPanel);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.SegmentujButton = uibutton(app.RightPanel, 'push');
            app.SegmentujButton.Text = 'Segmentuj';
            app.WeryfikujButton = uibutton(app.RightPanel, 'push');
            app.WeryfikujButton.Text = 'Weryfikuj';
            app.ObliczpowierzchnieButton = uibutton(app.RightPanel, 'push');
            app.ObliczpowierzchnieButton.Text = 'Oblicz powierzchnie';
            app.ObliczobjtoButton = uibutton(app.RightPanel, 'push');
            app.ObliczobjtoButton.Text = 'Oblicz objętość';
            app.PolepowierzchniLabel = uilabel(app.RightPanel);
            app.PolepowierzchniLabel.Text = 'Pole powierzchni:';
            app.wynikLabel = uilabel(app.RightPanel);
            app.wynikLabel.Text = 'wynik';
            app.ObjtoLabel = uilabel(app.RightPanel);
            app.ObjtoLabel.Text = 'Objętość:';
            app.wynikLabel_2 = uilabel(app.RightPanel);
            app.wynikLabel_2.Text = 'wynik';
            app.WynikiweryfikacjiLabel = uilabel(app.RightPanel);
            app.WynikiweryfikacjiLabel.Text = 'Wyniki weryfikacji:';
            app.wynikLabel_3 = uilabel(app.RightPanel);
            app.wynikLabel_3.Text = 'wynik';
            % Show the figure after all components are created
            app.SystemkomputerowegowspomaganiadiagnostykimedycznejUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = okno_glowne

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SystemkomputerowegowspomaganiadiagnostykimedycznejUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SystemkomputerowegowspomaganiadiagnostykimedycznejUIFigure)
        end
    end
end